classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BtnBuildMap             matlab.ui.control.StateButton
        MapBuildingLamp         matlab.ui.control.Lamp
        MapbuildingLabel        matlab.ui.control.Label
        Panel_2                 matlab.ui.container.Panel
        SldSpeed                matlab.ui.control.Slider
        SpeedLabel              matlab.ui.control.Label
        Panel_3                 matlab.ui.container.Panel
        AutoexploreSwitch       matlab.ui.control.Switch
        AutoexploreSwitchLabel  matlab.ui.control.Label
        SimulationLamp          matlab.ui.control.Lamp
        SimulationLampLabel     matlab.ui.control.Label
        ConnectionLamp          matlab.ui.control.Lamp
        ConnectionLampLabel     matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        BtnFullStop             matlab.ui.control.StateButton
        BtnBwdLeft              matlab.ui.control.StateButton
        BtnFwdLeft              matlab.ui.control.StateButton
        BtnBwdRight             matlab.ui.control.StateButton
        BtnFwdRight             matlab.ui.control.StateButton
        BtnLeft                 matlab.ui.control.StateButton
        BtnBackward             matlab.ui.control.StateButton
        BtnRight                matlab.ui.control.StateButton
        BtnForward              matlab.ui.control.StateButton
        BtnCW                   matlab.ui.control.StateButton
        BtnCCW                  matlab.ui.control.StateButton
        BtnSimulate             matlab.ui.control.Button
        BtnConnect              matlab.ui.control.Button
        MapOccupancy            matlab.ui.control.UIAxes
        MapTrajectory           matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties(Constant)

    end
    properties (Access = private)
        statusOffColor = [0.5, 0.5, 0.5];
        statusOnColor = 'green';
        wheelsSpeed = 10.0; 
        slamTimer % timer
        baseControl % Base control object
        connection  % Instance of RobotConnection
        laserScanner % Instance of LaserScanner class
        robotPose % Instance of RobotPoseEstimation class 
        slamHandler % Instance of the lidarSlam class
    end
    
    properties (Access = public)
       
    end
    
    methods (Access = private)

        function toggleConnectButton(app)
            if app.connection.clientID == -1
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';
                app.ConnectionLamp.Color = app.statusOffColor;
            else
                app.BtnConnect.Text = 'Disconnect';
                app.BtnConnect.Icon = 'icons/Disconnect.png';
                app.ConnectionLamp.Color = app.statusOnColor;
            end
        end

        function toggleSimulationButton(app,state)
            if app.connection.clientID > -1
                app.BtnSimulate.Enable = "on";
            else 
                app.BtnSimulate.Enable = state;
            end

            if app.connection.isSimulationRunning
                app.SimulationLamp.Color = app.statusOnColor;
                app.BtnSimulate.Icon = 'icons/Stop.png';
            else
                app.SimulationLamp.Color = app.statusOffColor;
                app.BtnSimulate.Icon = 'icons/Start.png';
            end
        end

        function toggleBuildMapButtons(app,state,lampStatus)

            app.BtnBuildMap.Enable = state;

            if strcmp(lampStatus,"off")
                app.MapBuildingLamp.Color = app.statusOffColor;
            else
                app.MapBuildingLamp.Color = app.statusOnColor;
            end
        end
 
        function toggleMovementButtons(app,state)
            
            app.SldSpeed.Enable = state;
            
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, ...
                               app.BtnCW, app.BtnCCW, app.BtnFwdLeft, app.BtnFwdRight, ...
                               app.BtnBwdLeft, app.BtnBwdRight, app.BtnFullStop};

            % Loop through all movement buttons and set their status
            for i = 1:length(movementButtons)
                movementButtons{i}.Enable = state;
            end
        end

        function toggleAutoExploreButton(app,state)
            app.AutoexploreSwitch.Enable = state;
        end

        function toggleControls(app,state)
            app.toggleSimulationButton(state);
            app.toggleBuildMapButtons(state,"off");
            app.toggleMovementButtons(state);
        end

        function buttonsInitialSetup(app)
        
            app.toggleConnectButton();
            app.toggleSimulationButton('off');
            app.toggleBuildMapButtons('off','off');
            app.toggleMovementButtons('off');
            app.toggleAutoExploreButton('off');

            %assign unique identifiers to the movement buttons so we can
            %have a single generic method to move the robot

            app.BtnForward.Tag = 'FORWARD';
            app.BtnBackward.Tag = 'BACKWARD';
            app.BtnLeft.Tag = 'LEFT';
            app.BtnRight.Tag = 'RIGHT';
            app.BtnCW.Tag = 'CLOCKWISE';
            app.BtnCCW.Tag = 'COUNTER_CLOCKWISE';
            app.BtnFwdLeft.Tag = 'DIAG_FL';
            app.BtnFwdRight.Tag = 'DIAG_FR';
            app.BtnBwdLeft.Tag = 'DIAG_BL';
            app.BtnBwdRight.Tag = 'DIAG_BR';
            app.BtnFullStop.Tag = 'FULL_STOP';

            app.SldSpeed.Limits = [1,50];
            app.SldSpeed.Value = app.wheelsSpeed;
        end

        function componentsInitialSetup(app)
                        
            % Retrieve wheel handles from CoppeliaSim (use correct object names)
            [handleWheelFR, ~] = app.connection.getHandle('rollingJoint_fr');
            [handleWheelFL, ~] = app.connection.getHandle('rollingJoint_fl');
            [handleWheelRR, ~] = app.connection.getHandle('rollingJoint_rr');
            [handleWheelRL, ~] = app.connection.getHandle('rollingJoint_rl');
            
            % Store the handles in an array to pass to BaseControl
            wheelHandles = [handleWheelFR, handleWheelFL, handleWheelRR, handleWheelRL];
            
            % Initialize the BaseControl object
            app.baseControl = BaseControl(wheelHandles);
           
            [baseHandle, ~] = app.connection.getHandle('/youBot');
            app.robotPose = RobotPoseEstimation(app.connection, baseHandle);

            [laserHandle, ~] = app.connection.getHandle('/youBot/Hokuyo/laser');    
            app.laserScanner = LaserScanner(app.connection, laserHandle);
            if app.laserScanner.isStreamingData
                disp('Laser data streaming initialized successfully.');
            else
                disp('Failed to initialize laser data streaming.');
            end

            % Initialize SLAM algorithm with new parameters
            maxRange = 8; % Slightly below the max Lidar range
            mapResolution = 20; % 20 cells per meter, 5cm precision
            loopClosureThreshold = 210;
            loopClosureSearchRadius = 8;
    
            % Initialize the SLAM handler object
            app.slamHandler = SLAMHandler(mapResolution, maxRange, loopClosureThreshold, loopClosureSearchRadius, app.robotPose, app.MapTrajectory, app.MapOccupancy);

            % Start laser data streaming here
            [returnCode, ~] = app.connection.sim.simxGetStringSignal(app.connection.clientID, 'laserData', app.connection.sim.simx_opmode_streaming);
            if returnCode == app.connection.sim.simx_return_ok || returnCode == app.connection.sim.simx_return_novalue_flag
                disp('Laser data streaming initialized successfully.');
            else
                disp('Failed to initialize laser data streaming.');
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize RobotConnection
            app.connection = RobotConnection();
            app.buttonsInitialSetup();
        end

        % Button pushed function: BtnConnect
        function BtnConnectButtonPushed(app, event)
            % Check the current state of the connection
            if app.connection.clientID == -1
                success = app.connection.connect();
                if success
                    disp('Connected to CoppeliaSim.');
                    app.componentsInitialSetup();
                    
                    % Change the connect button to disconnect
                    app.toggleConnectButton();

                    % Enable Simulation controls
                    app.toggleSimulationButton('on');
                else
                    %failed to connect to Coppelia
                     uialert(app.UIFigure, 'Connection failed.Something went wrong, please check the logs', 'Status');
                end
            else
                %check if it's running and stop the simulation 
                if app.connection.isSimulationRunning
                    app.connection.stopSimulation();
                    app.toggleAutoExploreButton('off');
                    stop(app.slamTimer);
                end
                app.connection.disconnect();

                % Change back the connect button to connect
                app.toggleConnectButton();

                % Disable UI controls 
                app.toggleControls("off");
            end
        end

        % Button pushed function: BtnSimulate
        function BtnSimulateButtonPushed(app, event)
            % Check if the client is connected
            if app.connection.clientID > -1

                 if ~app.connection.isSimulationRunning
                    app.connection.startSimulation();
                 
                    if app.connection.isSimulationRunning
                         % Enable UI controls
                        app.toggleControls('on');
                        app.toggleAutoExploreButton('on');

                    end
                 else
                     % Stop the simulation
                    app.connection.stopSimulation();
                    
                    % Check if the simulation stopped successfully
                    if ~app.connection.isSimulationRunning
                        % Disable UI controls
                        app.toggleControls('off');
                        app.toggleAutoExploreButton('off');
                        
                        stop(app.slamTimer);
                        disp('Simulation stopped.');
                    end
                end
            else
                disp('Please connect to CoppeliaSim before starting the simulation.');
            end
        end

        % Value changed function: SldSpeed
        function SldSpeedValueChanged(app, event)
            app.wheelsSpeed  = app.SldSpeed.Value;
        end

        % Value changed function: BtnBackward, BtnBwdLeft, BtnBwdRight, 
        % ...and 8 other components
        function BtnMovementValueChanged(app, event)
            value = event.Source.Value;
            movementType = event.Source.Tag;  % Get the movement type from the button's tag
            
            % Create a list of all movement buttons
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, ...
                               app.BtnFwdLeft, app.BtnFwdRight, app.BtnBwdLeft, app.BtnBwdRight, ...
                               app.BtnCW, app.BtnCCW};

            % Take out from the list the pressed button
            pressedButton = event.Source;
            remainingButtons = movementButtons(~cellfun(@(btn) isequal(btn, pressedButton), movementButtons));
            
            % Loop through all the other movement buttons and set their value to 0
                for i = 1:length(remainingButtons)
                    remainingButtons{i}.Value = 0;
                end

            % if the Full stop button is clicked
            if strcmp(movementType, 'FULL_STOP')
                % Stop the robot
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
                app.BtnFullStop.Value = 0;

            else
               % For other movement buttons, move the robot if pressed
                if value == 1  % Button is pressed
                    app.baseControl.move(app.connection.sim, app.connection.clientID,app.baseControl.WheelMovements.(movementType), app.wheelsSpeed);
                else
                    app.baseControl.stop(app.connection.sim, app.connection.clientID);
                end
            end
        end

        % Value changed function: BtnBuildMap
        function BtnBuildMapButtonPushed(app, event)
            value = event.Source.Value;
            if value == 1
                app.slamTimer = timer('ExecutionMode','fixedRate', ...
                    'Period', 1, ...
                    'TimerFcn', @(~, ~) app.slamHandler.updateSLAM(app.connection));
                start(app.slamTimer);
                app.toggleBuildMapButtons('on','on');
            else
                 stop(app.slamTimer);
                 app.toggleBuildMapButtons('on','off');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MapTrajectory = uiaxes(app.UIFigure);
            title(app.MapTrajectory, 'Trajectory map')
            xlabel(app.MapTrajectory, 'X')
            ylabel(app.MapTrajectory, 'Y')
            zlabel(app.MapTrajectory, 'Z')
            app.MapOccupancy = uiaxes(app.UIFigure);
            title(app.MapOccupancy, 'Occupancy map')
            xlabel(app.MapOccupancy, 'X')
            ylabel(app.MapOccupancy, 'Y')
            zlabel(app.MapOccupancy, 'Z')
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.BtnSimulate = uibutton(app.UIFigure, 'push');
            app.BtnSimulate.ButtonPushedFcn = createCallbackFcn(app, @BtnSimulateButtonPushed, true);
            app.BtnSimulate.Icon = fullfile(pathToMLAPP, 'icons', 'Start.png');
            app.BtnSimulate.Enable = 'off';
            app.BtnSimulate.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.BtnCCW = uibutton(app.Panel, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.Panel, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            app.BtnForward = uibutton(app.Panel, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.Panel, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.Panel, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.Panel, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFwdRight = uibutton(app.Panel, 'state');
            app.BtnFwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFwdRight.Enable = 'off';
            app.BtnFwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'FwdRight.png');
            app.BtnFwdRight.Text = '';
            app.BtnBwdRight = uibutton(app.Panel, 'state');
            app.BtnBwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBwdRight.Enable = 'off';
            app.BtnBwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'BwdRight.png');
            app.BtnBwdRight.Text = '';
            app.BtnFwdLeft = uibutton(app.Panel, 'state');
            app.BtnFwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFwdLeft.Enable = 'off';
            app.BtnFwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'FwdLeft.png');
            app.BtnFwdLeft.Text = '';
            app.BtnBwdLeft = uibutton(app.Panel, 'state');
            app.BtnBwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBwdLeft.Enable = 'off';
            app.BtnBwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'BwdLeft.png');
            app.BtnBwdLeft.Text = '';
            app.BtnFullStop = uibutton(app.Panel, 'state');
            app.BtnFullStop.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFullStop.Enable = 'off';
            app.BtnFullStop.Icon = fullfile(pathToMLAPP, 'icons', 'Stop.png');
            app.BtnFullStop.Text = '';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.SimulationLampLabel = uilabel(app.UIFigure);
            app.SimulationLampLabel.Text = 'Simulation';
            app.SimulationLamp = uilamp(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.AutoexploreSwitchLabel = uilabel(app.Panel_3);
            app.AutoexploreSwitchLabel.Enable = 'off';
            app.AutoexploreSwitchLabel.Text = 'Auto explore';
            app.AutoexploreSwitch = uiswitch(app.Panel_3, 'slider');
            app.AutoexploreSwitch.Enable = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.SpeedLabel = uilabel(app.Panel_2);
            app.SpeedLabel.Enable = 'off';
            app.SpeedLabel.Text = 'Speed';
            app.SldSpeed = uislider(app.Panel_2);
            app.SldSpeed.Limits = [0 50];
            app.SldSpeed.ValueChangedFcn = createCallbackFcn(app, @SldSpeedValueChanged, true);
            app.SldSpeed.Enable = 'off';
            app.MapbuildingLabel = uilabel(app.UIFigure);
            app.MapbuildingLabel.Text = 'Map building';
            app.MapBuildingLamp = uilamp(app.UIFigure);
            app.BtnBuildMap = uibutton(app.UIFigure, 'state');
            app.BtnBuildMap.ValueChangedFcn = createCallbackFcn(app, @BtnBuildMapButtonPushed, true);
            app.BtnBuildMap.Enable = 'off';
            app.BtnBuildMap.Icon = fullfile(pathToMLAPP, 'icons', 'map.png');
            app.BtnBuildMap.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end